classdef MainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Sample1Button                   matlab.ui.control.Button
        Sample4Button                   matlab.ui.control.Button
        Sample7Button                   matlab.ui.control.Button
        Sample2Button                   matlab.ui.control.Button
        Sample5Button                   matlab.ui.control.Button
        Sample8Button                   matlab.ui.control.Button
        Sample3Button                   matlab.ui.control.Button
        Sample6Button                   matlab.ui.control.Button
        Sample9Button                   matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        EditSampleOpenEditWindowButton  matlab.ui.control.Button
        AddEffectsOpenEffectsSuiteButton  matlab.ui.control.Button
        OPTIONSLabel                    matlab.ui.control.Label
        MatRixLabel                     matlab.ui.control.Label
        LoadNewSampleButton             matlab.ui.control.Button
        ClearExistingSampleButton       matlab.ui.control.Button
        CreateCustomSampleOpenSynthesizerButton  matlab.ui.control.Button
        AudioProcessingWorkstationSynthesizerLabel  matlab.ui.control.Label
        ChrisMillsModelENG006v165Label  matlab.ui.control.Label
        StartatLoadNewSampleBelowLabel  matlab.ui.control.Label
    end

    %%%%%% Notes %%%%%%%
    %%% Created by Chris Mills for a final project in ENG 006, UC Davis
    % See notes in file 'track.m' for more information
    % Version 1.6.5
    % Originally created 11/25/2019
    % Revised 11/26
    % Revised 12/2 to accomodate wave generator
    % Revised 12/4 - General cosmetics
    % Revised 12/5 - Accomodate Chopper
    % Latest revision: 12/16/2019 - Updated Clear and Create Buttons to
    % generate choice menus instead of relying on last clicked
    % To do: Set up some functionality to show if a button is loaded or not
    % Fix bug where when the user wants to load a sample, but they click x
    %in the file window, don't get an error
    % Allow user to load files from anywhere on their computer, not just
    % the MATLAB directory.
    % Set up functionality to show which button was clicked last.
    
    properties (Access = private)
        %Custom defined properties
        samples                % samples is an array of structures...
                               % ... it contains the track and isEmpty
        lastClicked            int8 
        listCell               cell
    end
  
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
        
        function playSample(app, num)
            app.lastClicked = num;
            if app.samples(num).isEmpty
                helpdlg('No sample loaded!', 'Empty');
            else
                app.samples(num).track.play();
            end
        end
        
        function clearSample(app, num)
            message = sprintf('Are you sure you want to clear %d?',num);
            answer = questdlg(message, 'Clear sample?', 'No');
            if ~isempty(answer)
                if strcmpi(answer,'Yes')
                    if app.samples(num).isEmpty
                        helpdlg('No sample loaded!', 'Empty');
                    else
                        app.samples(num).track = [];
                        app.samples(num).isEmpty = true;
                        app.lastClicked = num;
                    end
                end
            end
        end
        
        function loadSample(app,num)
            file = uigetfile;
            [inArray, sampleFreq] = audioread(file);
            app.samples(num).track = track(inArray, sampleFreq);
            app.samples(num).isEmpty = false;
            app.lastClicked = num;
        end
        
        function value = selectSample(app)
            charVectCaption = 'Which slot?';
            sampleChoice = listdlg('ListString',app.listCell,...
            'PromptString',charVectCaption,'SelectionMode','single',...
            'ListSize',[200,150]);
            if ~isempty(sampleChoice)
                value = int8(sampleChoice);
            else
                value = 0;
            end
        end
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lastClicked = 1;
            for i = 1:9
                app.samples(i).track = [];
                app.samples(i).isEmpty = true;
            end
            app.listCell = {'Sample 1','Sample 2','Sample 3',...
                'Sample 4','Sample 5','Sample 6',...
                'Sample 7','Sample 8','Sample 9'};
        end

        % Button pushed function: LoadNewSampleButton
        function LoadNewSampleButtonPushed(app, event)
            value = app.selectSample;
            if value>0
                if app.samples(value).isEmpty
                    app.loadSample(value);
                else
                    message = ('This slot already contains a sample. Do you wish to clear it?');
                    answer = questdlg(message, 'Overwrite existing data?', 'modal');
                    if strcmpi(answer,'Yes')
                        app.loadSample(value);
                    end
                end
            end
        end

        % Button pushed function: Sample1Button
        function Sample1ButtonPushed(app, event)
            i = 1;
            app.playSample(i);
        end

        % Button pushed function: Sample2Button
        function Sample2ButtonPushed(app, event)
            i = 2;
            app.playSample(i);
        end

        % Button pushed function: Sample3Button
        function Sample3ButtonPushed(app, event)
            i = 3;
            app.playSample(i); 
        end

        % Button pushed function: Sample4Button
        function Sample4ButtonPushed(app, event)
            i = 4;
            app.playSample(i);
        end

        % Button pushed function: Sample5Button
        function Sample5ButtonPushed(app, event)
            i = 5;
            app.playSample(i);    
        end

        % Button pushed function: Sample6Button
        function Sample6ButtonPushed(app, event)
            i = 6;
            app.playSample(i);    
        end

        % Button pushed function: Sample7Button
        function Sample7ButtonPushed(app, event)
            i = 7;
            app.playSample(i);    
        end

        % Button pushed function: Sample8Button
        function Sample8ButtonPushed(app, event)
            i = 8;
            app.playSample(i);    
        end

        % Button pushed function: Sample9Button
        function Sample9ButtonPushed(app, event)
            i = 9;
            app.playSample(i);    
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ClearExistingSampleButton
        function ClearExistingSampleButtonPushed(app, event)
            value = app.selectSample;
            if value>0
                app.clearSample(value);
            end
        end

        % Button pushed function: AddEffectsOpenEffectsSuiteButton
        function AddEffectsOpenEffectsSuiteButtonPushed(app, event)
            num = app.lastClicked;
            if app.samples(num).isEmpty
                helpdlg('No sample loaded!', 'Empty');
            else
               SampleEditingSuite(app.samples(num).track, app.lastClicked);
            end
        end

        % Button pushed function: 
        % CreateCustomSampleOpenSynthesizerButton
        function CreateCustomSampleOpenSynthesizerButtonPushed(app, event)
            value = app.selectSample;
            if value>0
                if app.samples(value).isEmpty
                    app.samples(value).track = track([1,2,3],1000);
                    app.samples(value).isEmpty = false;
                    WaveGenerator(app.samples(value).track);
                else
                    message = ('This slot already contains a sample. Do you wish to clear it?');
                    answer = questdlg(message, 'Overwrite existing data?', 'modal');
                    if strcmpi(answer,'Yes')
                        app.clearSample(value);
                        app.samples(value).track = track([1,2,3],1000);
                        app.samples(value).isEmpty = false;
                        WaveGenerator(app.samples(value).track);
                    end
                end
            end            
        end

        % Button pushed function: EditSampleOpenEditWindowButton
        function EditSampleOpenEditWindowButtonPushed(app, event)
            num = app.lastClicked;
            if app.samples(num).isEmpty
                helpdlg('No sample loaded!', 'Empty');
            else
               Chopper(app.samples(num).track);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Sample1Button = uibutton(app.GridLayout, 'push');
            app.Sample1Button.ButtonPushedFcn = createCallbackFcn(app, @Sample1ButtonPushed, true);
            app.Sample1Button.Text = 'Sample 1';
            app.Sample4Button = uibutton(app.GridLayout, 'push');
            app.Sample4Button.ButtonPushedFcn = createCallbackFcn(app, @Sample4ButtonPushed, true);
            app.Sample4Button.Text = 'Sample 4';
            app.Sample7Button = uibutton(app.GridLayout, 'push');
            app.Sample7Button.ButtonPushedFcn = createCallbackFcn(app, @Sample7ButtonPushed, true);
            app.Sample7Button.Text = 'Sample 7';
            app.Sample2Button = uibutton(app.GridLayout, 'push');
            app.Sample2Button.ButtonPushedFcn = createCallbackFcn(app, @Sample2ButtonPushed, true);
            app.Sample2Button.Text = 'Sample 2';
            app.Sample5Button = uibutton(app.GridLayout, 'push');
            app.Sample5Button.ButtonPushedFcn = createCallbackFcn(app, @Sample5ButtonPushed, true);
            app.Sample5Button.Text = 'Sample 5';
            app.Sample8Button = uibutton(app.GridLayout, 'push');
            app.Sample8Button.ButtonPushedFcn = createCallbackFcn(app, @Sample8ButtonPushed, true);
            app.Sample8Button.Text = 'Sample 8';
            app.Sample3Button = uibutton(app.GridLayout, 'push');
            app.Sample3Button.ButtonPushedFcn = createCallbackFcn(app, @Sample3ButtonPushed, true);
            app.Sample3Button.Text = 'Sample 3';
            app.Sample6Button = uibutton(app.GridLayout, 'push');
            app.Sample6Button.ButtonPushedFcn = createCallbackFcn(app, @Sample6ButtonPushed, true);
            app.Sample6Button.Text = 'Sample 6';
            app.Sample9Button = uibutton(app.GridLayout, 'push');
            app.Sample9Button.ButtonPushedFcn = createCallbackFcn(app, @Sample9ButtonPushed, true);
            app.Sample9Button.Text = 'Sample 9';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'S T O P';
            app.EditSampleOpenEditWindowButton = uibutton(app.GridLayout, 'push');
            app.EditSampleOpenEditWindowButton.ButtonPushedFcn = createCallbackFcn(app, @EditSampleOpenEditWindowButtonPushed, true);
            app.EditSampleOpenEditWindowButton.Text = {'Edit Sample'; '(Open Edit Window)'};
            app.AddEffectsOpenEffectsSuiteButton = uibutton(app.GridLayout, 'push');
            app.AddEffectsOpenEffectsSuiteButton.ButtonPushedFcn = createCallbackFcn(app, @AddEffectsOpenEffectsSuiteButtonPushed, true);
            app.AddEffectsOpenEffectsSuiteButton.Text = {'Add Effects'; '(Open Effects Suite)'};
            app.OPTIONSLabel = uilabel(app.GridLayout);
            app.OPTIONSLabel.Text = 'OPTIONS';
            app.MatRixLabel = uilabel(app.GridLayout);
            app.MatRixLabel.Text = 'M a t R i x';
            app.LoadNewSampleButton = uibutton(app.GridLayout, 'push');
            app.LoadNewSampleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewSampleButtonPushed, true);
            app.LoadNewSampleButton.Text = 'Load New Sample';
            app.ClearExistingSampleButton = uibutton(app.GridLayout, 'push');
            app.ClearExistingSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ClearExistingSampleButtonPushed, true);
            app.ClearExistingSampleButton.Text = 'Clear Existing Sample';
            app.CreateCustomSampleOpenSynthesizerButton = uibutton(app.GridLayout, 'push');
            app.CreateCustomSampleOpenSynthesizerButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCustomSampleOpenSynthesizerButtonPushed, true);
            app.CreateCustomSampleOpenSynthesizerButton.Text = {'Create Custom Sample'; '(Open Synthesizer)'};
            app.AudioProcessingWorkstationSynthesizerLabel = uilabel(app.GridLayout);
            app.AudioProcessingWorkstationSynthesizerLabel.Text = {'Audio Processing Workstation'; '+'; 'Synthesizer'};
            app.ChrisMillsModelENG006v165Label = uilabel(app.GridLayout);
            app.ChrisMillsModelENG006v165Label.Text = {'Chris Mills'; 'Model ENG-006'; 'v 1.6.5'};
            app.StartatLoadNewSampleBelowLabel = uilabel(app.GridLayout);
            app.StartatLoadNewSampleBelowLabel.Text = 'Start at "Load New Sample" Below!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end