classdef SampleEditingSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SAMPLEEDITINGSUITELabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ONOFFButton                   matlab.ui.control.Button
        DELAYLabel                    matlab.ui.control.Label
        RepeatsKnobLabel              matlab.ui.control.Label
        RepeatsKnob                   matlab.ui.control.DiscreteKnob
        TimemsKnob_2Label             matlab.ui.control.Label
        TimemsKnob_2                  matlab.ui.control.Knob
        VolumeKnobLabel               matlab.ui.control.Label
        VolumeKnob                    matlab.ui.control.Knob
        AnalogSimSwitchLabel          matlab.ui.control.Label
        AnalogSimSwitch               matlab.ui.control.ToggleSwitch
        Lamp                          matlab.ui.control.Lamp
        Panel_2                       matlab.ui.container.Panel
        HiFrequencyCutoffLabel        matlab.ui.control.Label
        HiFrequencyCutoffSlider       matlab.ui.control.Slider
        LoFrequencyCutoffSliderLabel  matlab.ui.control.Label
        LoFrequencyCutoffSlider       matlab.ui.control.Slider
        FilterTypeButtonGroup         matlab.ui.container.ButtonGroup
        LowPassButton                 matlab.ui.control.ToggleButton
        HighPassButton                matlab.ui.control.ToggleButton
        BandPassButton                matlab.ui.control.ToggleButton
        ONOFFButton_2                 matlab.ui.control.Button
        FILTERLabel                   matlab.ui.control.Label
        Panel_3                       matlab.ui.container.Panel
        Gauge                         matlab.ui.control.Gauge
        FASTERButton                  matlab.ui.control.Button
        slowerButton                  matlab.ui.control.Button
        reverttooriginalspeedButton   matlab.ui.control.Button
        ManualInputEditFieldLabel     matlab.ui.control.Label
        ManualInputEditField          matlab.ui.control.NumericEditField
        PLAYButton                    matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        REVERSEButton                 matlab.ui.control.Button
        SampleLabel                   matlab.ui.control.Label
        VOICEREMOVERButton            matlab.ui.control.StateButton
    end

    %%%%%% Notes %%%%%%%
    %%% Created by Chris Mills for a final project in ENG 006, UC Davis
    % See notes in file 'track.m' for more information
    % Version 1.6.5
    % Originally created 11/16/2019
    % Revised 11/23 - Added analog sim/restructured delay
    % Revised 11/25 - Now communicates with main interface
    % Revised 11/26 - Added voice removal
    % Revised 12/4 - Added manual speed input, doubled range of delay time
    % Added label, general cosmetics
    % To do: Cosmetics (fonts don't always transfer), add extra features
    % (see track.m) 
    
    
    properties (Access = private)
        sample                      track   % Custom class
        %All of the below are logicals representing "Is this effect on?"
        delayOnStatus               logical 
        filterOnStatus              logical 
        reverseStatus               logical 
        analogStatus                logical
        removeStatus                logical
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
        
        %
        function setFilterType(app)
            if  app.LowPassButton.Value
                app.sample.setFilterType(1);
            %Below is cosmetics
                app.LoFrequencyCutoffSlider.Enable = 'off';
                app.HiFrequencyCutoffSlider.Enable = 'on';
                app.LoFrequencyCutoffSliderLabel.Enable = 'off';
                app.HiFrequencyCutoffLabel.Enable = 'on';
            elseif app.HighPassButton.Value
                app.sample.setFilterType(2);
            %Below is cosmetics
                app.HiFrequencyCutoffSlider.Enable = 'off';
                app.LoFrequencyCutoffSlider.Enable = 'on';
                app.LoFrequencyCutoffSliderLabel.Enable = 'on';
                app.HiFrequencyCutoffLabel.Enable = 'off';
            else
                %Assumes band pass is selected
                app.sample.setFilterType(3);
            %Below is cosmetics
                app.HiFrequencyCutoffSlider.Enable = 'on';
                app.LoFrequencyCutoffSlider.Enable = 'on';
                app.LoFrequencyCutoffSliderLabel.Enable = 'on';
                app.HiFrequencyCutoffLabel.Enable = 'on';
            end
            app.sample.setFilter();
        end
        
        %
        function delayReinitialize(app)
            app.sample.delayOff();
            app.sample.delayOn();
            app.sample.setVolume(double(app.VolumeKnob.Value)/100.0);
            app.sample.setTime(int64(app.TimemsKnob_2.Value));
            app.sample.setNumRepeats(str2num(app.RepeatsKnob.Value));
            if app.analogStatus
                app.sample.analogOn;
            end
        end
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sample, sampleNum)
            %Assumes sample is of class "track"
            app.sample = sample;
            app.delayOnStatus = false;
            app.filterOnStatus = false;
            app.reverseStatus = false;
            app.analogStatus = false;
            app.removeStatus = false;
            app.SampleLabel.Text = sprintf('Sample # %d',sampleNum);
        end

        % Button pushed function: ONOFFButton
        function ONOFFButtonPushed(app, event)
            %This is the delay on/off button
            if app.delayOnStatus == false
                app.delayReinitialize();
                app.delayOnStatus = true;
                %Everything below here is just cosmetics
                app.ONOFFButton.BackgroundColor = [0.06,1.00,1.00];
                app.RepeatsKnob.Enable = 'on';
                app.RepeatsKnobLabel.Enable = 'on';
                app.VolumeKnob.Enable = 'on';
                app.VolumeKnobLabel.Enable = 'on';
                app.TimemsKnob_2.Enable = 'on';
                app.TimemsKnob_2Label.Enable = 'on';
                app.AnalogSimSwitch.Enable = 'on';
                app.AnalogSimSwitchLabel.Enable = 'on';
                app.Lamp.Enable = 'on';
            else
                app.sample.delayOff();
                app.delayOnStatus = false;
                %Everything below here is just cosmetics
                app.ONOFFButton.BackgroundColor = [0.50,0.50,0.50];
                app.RepeatsKnob.Enable = 'off';
                app.VolumeKnob.Enable = 'off';
                app.TimemsKnob_2.Enable = 'off';
                app.AnalogSimSwitch.Enable = 'off';
                app.AnalogSimSwitchLabel.Enable = 'off';
                app.Lamp.Enable = 'off';
            end
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            %Controls the volume of the repeats
            %100% = same volume as the actual sample, 0% = inaudible
            value = double(app.VolumeKnob.Value)/100.0;
            app.sample.setVolume(value);       
        end

        % Value changed function: TimemsKnob_2
        function TimemsKnob_2ValueChanged(app, event)
            %Controls the time between each repeat
            value = int64(app.TimemsKnob_2.Value);
            app.sample.setTime(value);
        end

        % Value changed function: RepeatsKnob
        function RepeatsKnobValueChanged(app, event)
            %Controls the number of repeats played
            value = str2num(app.RepeatsKnob.Value);
            app.sample.setNumRepeats(value);       
        end

        % Button pushed function: ONOFFButton_2
        function ONOFFButton_2Pushed(app, event)
            %On/Off button for filter 
            if app.filterOnStatus == false
                app.sample.filterOn();
                app.sample.setLoPass(app.HiFrequencyCutoffSlider.Value);
                app.sample.setHiPass(app.LoFrequencyCutoffSlider.Value);
                app.setFilterType();
                %Note this is calling the setFilterType function in the
                %GUI, NOT the one defined within the track class.
                app.filterOnStatus = true;
            %Below is cosmetics
                app.ONOFFButton_2.BackgroundColor = [0.30,0.75,0.93];
                app.HighPassButton.Enable = 'on';
                app.LowPassButton.Enable = 'on';
                app.BandPassButton.Enable = 'on';
             else
                app.sample.filterOff();
                app.filterOnStatus = false;
            %Below is cosmetics
                app.ONOFFButton_2.BackgroundColor = [0.50,0.50,0.50];
                app.HiFrequencyCutoffSlider.Enable = 'off';
                app.LoFrequencyCutoffSlider.Enable = 'off';
                app.HighPassButton.Enable = 'off';
                app.LowPassButton.Enable = 'off';
                app.BandPassButton.Enable = 'off';
                app.LoFrequencyCutoffSliderLabel.Enable = 'off';
                app.HiFrequencyCutoffLabel.Enable = 'off';
            end
        end

        % Value changed function: HiFrequencyCutoffSlider
        function HiFrequencyCutoffSliderValueChanged(app, event)
            value = app.HiFrequencyCutoffSlider.Value;
            app.sample.setLoPass(value);
            app.sample.setFilter();
        end

        % Value changed function: LoFrequencyCutoffSlider
        function LoFrequencyCutoffSliderValueChanged(app, event)
            value = app.LoFrequencyCutoffSlider.Value;
            app.sample.setHiPass(value);
            app.sample.setFilter();
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            app.setFilterType();
            %Note: Calling the setFilterType fxn defined in the GUI, not
            %the one defined in the class. (ie not
            %app.sample.setFilterType())
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            %Reinitializing delay each time allows the other effects to
            %talk to the delay (ie the repeats will not have effects on
            %them otherwise)
            if app.delayOnStatus
               app.delayReinitialize();
            end
            app.sample.play();
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % Killswitch
            app.sample.stop();
        end

        % Button pushed function: REVERSEButton
        function REVERSEButtonPushed(app, event)
            if app.reverseStatus == false
                app.REVERSEButton.BackgroundColor = [1.00,0.3,0.7];
                app.sample.reverseOn();
                app.reverseStatus = true;
            else
                app.REVERSEButton.BackgroundColor = [0.4941 0.0 0.5569];
                app.sample.reverseOff();
                app.reverseStatus = false;
            end
        end

        % Button pushed function: FASTERButton
        function FASTERButtonPushed(app, event)
            if app.sample.getSpeed() < 3
                app.sample.speedUp;
                value = app.sample.getSpeed();
                app.Gauge.Value = value;
                app.ManualInputEditField.Value = value;
            end
        end

        % Button pushed function: slowerButton
        function slowerButtonPushed(app, event)
            if app.sample.getSpeed() > 0.5
                app.sample.slowDown;
                value = app.sample.getSpeed();
                app.Gauge.Value = value;
                app.ManualInputEditField.Value = value;
            end
        end

        % Button pushed function: reverttooriginalspeedButton
        function reverttooriginalspeedButtonPushed(app, event)
            app.sample.revert();
            value = app.sample.getSpeed();
            app.Gauge.Value = value;
            app.ManualInputEditField.Value = value;
        end

        % Value changed function: AnalogSimSwitch
        function AnalogSimSwitchValueChanged(app, event)
            %Activates the "analog sim" feature, which simulates a tape
            %echo or bucket brigade type analog delay by decreasing the
            %sound quality with each repeat.
            value = app.AnalogSimSwitch.Value;
            if strcmpi(value,'On')
                app.analogStatus = true;
                app.sample.analogOn();
                app.Lamp.Color = [1.00,1.00,0.07];
            else
                app.analogStatus = false;
                app.sample.analogOff();
                app.Lamp.Color = [0.15,0.15,0.15];
            end
        end

        % Value changed function: VOICEREMOVERButton
        function VOICEREMOVERButtonValueChanged(app, event)
            if app.removeStatus == false
                app.sample.removeCenter();
                app.removeStatus = true;
                %Cosmetics
                app.VOICEREMOVERButton.FontColor = [0 0 0];
                app.VOICEREMOVERButton.BackgroundColor = [1,1,0.667];
            else
                app.sample.unremoveCenter();
                app.removeStatus = false;
                app.VOICEREMOVERButton.FontColor = [1 1 0.0667];
                app.VOICEREMOVERButton.BackgroundColor = [0,0,0];
            end
        end

        % Value changed function: ManualInputEditField
        function ManualInputEditFieldValueChanged(app, event)
            value = app.ManualInputEditField.Value;
            app.sample.setSpeed(value);
            app.Gauge.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SAMPLEEDITINGSUITELabel = uilabel(app.UIFigure);
            app.SAMPLEEDITINGSUITELabel.Text = 'SAMPLE EDITING SUITE';
            app.Panel = uipanel(app.UIFigure);
            app.ONOFFButton = uibutton(app.Panel, 'push');
            app.ONOFFButton.ButtonPushedFcn = createCallbackFcn(app, @ONOFFButtonPushed, true);
            app.ONOFFButton.Text = 'ON/OFF';
            app.DELAYLabel = uilabel(app.Panel);
            app.DELAYLabel.Text = 'D E L A Y';
            app.RepeatsKnobLabel = uilabel(app.Panel);
            app.RepeatsKnobLabel.Enable = 'off';
            app.RepeatsKnobLabel.Text = 'Repeats';
            app.RepeatsKnob = uiknob(app.Panel, 'discrete');
            app.RepeatsKnob.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.RepeatsKnob.ValueChangedFcn = createCallbackFcn(app, @RepeatsKnobValueChanged, true);
            app.RepeatsKnob.Enable = 'off';
            app.RepeatsKnob.Value = '1';
            app.TimemsKnob_2Label = uilabel(app.Panel);
            app.TimemsKnob_2Label.Enable = 'off';
            app.TimemsKnob_2Label.Text = 'Time (ms)';
            app.TimemsKnob_2 = uiknob(app.Panel, 'continuous');
            app.TimemsKnob_2.Limits = [10 1200];
            app.TimemsKnob_2.MajorTicks = [10 200 400 600 800 1000 1200];
            app.TimemsKnob_2.ValueChangedFcn = createCallbackFcn(app, @TimemsKnob_2ValueChanged, true);
            app.TimemsKnob_2.MinorTicks = [10 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500 525 550 575 600];
            app.TimemsKnob_2.Enable = 'off';
            app.TimemsKnob_2.Value = 600;
            app.VolumeKnobLabel = uilabel(app.Panel);
            app.VolumeKnobLabel.Enable = 'off';
            app.VolumeKnobLabel.Tooltip = {'"Adjust volume of delayed signal"'};
            app.VolumeKnobLabel.Text = 'Volume (%)';
            app.VolumeKnob = uiknob(app.Panel, 'continuous');
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.Enable = 'off';
            app.VolumeKnob.Value = 50;
            app.AnalogSimSwitchLabel = uilabel(app.Panel);
            app.AnalogSimSwitchLabel.Enable = 'off';
            app.AnalogSimSwitchLabel.Text = {'Analog'; 'Sim'};
            app.AnalogSimSwitch = uiswitch(app.Panel, 'toggle');
            app.AnalogSimSwitch.ValueChangedFcn = createCallbackFcn(app, @AnalogSimSwitchValueChanged, true);
            app.AnalogSimSwitch.Enable = 'off';
            app.Lamp = uilamp(app.Panel);
            app.Lamp.Enable = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.HiFrequencyCutoffLabel = uilabel(app.Panel_2);
            app.HiFrequencyCutoffLabel.Enable = 'off';
            app.HiFrequencyCutoffLabel.Text = {'Hi-Frequency'; ' Cutoff'};
            app.HiFrequencyCutoffSlider = uislider(app.Panel_2);
            app.HiFrequencyCutoffSlider.Limits = [0 16000];
            app.HiFrequencyCutoffSlider.MajorTicks = [0 4000 8000 12000 16000];
            app.HiFrequencyCutoffSlider.MajorTickLabels = {'0', '4K', '8K', '12K', '16K'};
            app.HiFrequencyCutoffSlider.ValueChangedFcn = createCallbackFcn(app, @HiFrequencyCutoffSliderValueChanged, true);
            app.HiFrequencyCutoffSlider.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000 10500 11000 11500 12000 12500 13000 13500 14000 14500 15000 15500 16000];
            app.HiFrequencyCutoffSlider.Enable = 'off';
            app.HiFrequencyCutoffSlider.Value = 16000;
            app.LoFrequencyCutoffSliderLabel = uilabel(app.Panel_2);
            app.LoFrequencyCutoffSliderLabel.Enable = 'off';
            app.LoFrequencyCutoffSliderLabel.Text = {'Lo-Frequency'; 'Cutoff'};
            app.LoFrequencyCutoffSlider = uislider(app.Panel_2);
            app.LoFrequencyCutoffSlider.Limits = [0 4000];
            app.LoFrequencyCutoffSlider.MajorTicks = [0 1000 2000 3000 4000];
            app.LoFrequencyCutoffSlider.MajorTickLabels = {'0', '1K', '2K', '3K', '4K'};
            app.LoFrequencyCutoffSlider.ValueChangedFcn = createCallbackFcn(app, @LoFrequencyCutoffSliderValueChanged, true);
            app.LoFrequencyCutoffSlider.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000];
            app.LoFrequencyCutoffSlider.Enable = 'off';
            app.FilterTypeButtonGroup = uibuttongroup(app.Panel_2);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowPassButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.LowPassButton.Enable = 'off';
            app.LowPassButton.Text = 'Low-Pass';
            app.LowPassButton.Value = true;
            app.HighPassButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.HighPassButton.Enable = 'off';
            app.HighPassButton.Text = 'High-Pass';
            app.BandPassButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.BandPassButton.Enable = 'off';
            app.BandPassButton.Text = 'Band-Pass';
            app.ONOFFButton_2 = uibutton(app.Panel_2, 'push');
            app.ONOFFButton_2.ButtonPushedFcn = createCallbackFcn(app, @ONOFFButton_2Pushed, true);
            app.ONOFFButton_2.Text = 'ON/OFF';
            app.FILTERLabel = uilabel(app.Panel_2);
            app.FILTERLabel.Text = 'F I L T E R';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Gauge = uigauge(app.Panel_3, 'circular');
            app.Gauge.Limits = [0.5 3];
            app.Gauge.MajorTicks = [0.5 1 1.5 2 2.5 3];
            app.Gauge.MinorTicks = [0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3];
            app.Gauge.Value = 1;
            app.FASTERButton = uibutton(app.Panel_3, 'push');
            app.FASTERButton.ButtonPushedFcn = createCallbackFcn(app, @FASTERButtonPushed, true);
            app.FASTERButton.Text = {'F'; 'A'; 'S'; 'T'; 'E'; 'R'};
            app.slowerButton = uibutton(app.Panel_3, 'push');
            app.slowerButton.ButtonPushedFcn = createCallbackFcn(app, @slowerButtonPushed, true);
            app.slowerButton.Text = {'s'; 'l'; 'o'; 'w'; 'e'; 'r'};
            app.reverttooriginalspeedButton = uibutton(app.Panel_3, 'push');
            app.reverttooriginalspeedButton.ButtonPushedFcn = createCallbackFcn(app, @reverttooriginalspeedButtonPushed, true);
            app.reverttooriginalspeedButton.Text = {'revert to'; 'original speed'};
            app.ManualInputEditFieldLabel = uilabel(app.Panel_3);
            app.ManualInputEditFieldLabel.Text = 'Manual Input:';
            app.ManualInputEditField = uieditfield(app.Panel_3, 'numeric');
            app.ManualInputEditField.Limits = [0.5 3];
            app.ManualInputEditField.ValueDisplayFormat = '%11.1f';
            app.ManualInputEditField.ValueChangedFcn = createCallbackFcn(app, @ManualInputEditFieldValueChanged, true);
            app.ManualInputEditField.Value = 1;
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'P L A Y';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'S T O P';
            app.REVERSEButton = uibutton(app.UIFigure, 'push');
            app.REVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @REVERSEButtonPushed, true);
            app.REVERSEButton.Text = 'R E V E R S E';
            app.SampleLabel = uilabel(app.UIFigure);
            app.SampleLabel.Text = 'Sample #';
            app.VOICEREMOVERButton = uibutton(app.UIFigure, 'state');
            app.VOICEREMOVERButton.ValueChangedFcn = createCallbackFcn(app, @VOICEREMOVERButtonValueChanged, true);
            app.VOICEREMOVERButton.Text = {'V O I C E '; 'R E M O V E R'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SampleEditingSuite(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end