classdef WaveGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DurationsecondsSliderLabel  matlab.ui.control.Label
        DurationsecondsSlider       matlab.ui.control.Slider
        WavetypeButtonGroup         matlab.ui.container.ButtonGroup
        SineButton                  matlab.ui.control.RadioButton
        SquareButton                matlab.ui.control.RadioButton
        SawtoothButton              matlab.ui.control.RadioButton
        PlayButton                  matlab.ui.control.Button
        FrequencyEditFieldLabel     matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        WaveGeneratorLabel          matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        NoteDropDownLabel           matlab.ui.control.Label
        NoteDropDown                matlab.ui.control.DropDown
        ORLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        sample;                     % class track
        waveType;                   % int: 1 sine, 2 square, 3 sawtooth
        duration;                   % in seconds
        freq;                       % in Hertz
        sampleFreq;                 % constant
        wave;                       % The actual array containing the sound
        notes                       % array of strings
        noteFreqs                   % array of doubles
    end
    
    methods (Access = private)
        
    %%%%%% Notes %%%%%%%
    %%% Created by Chris Mills for a final project in ENG 006, UC Davis
    % See notes in file 'track.m' for more information
    % Version 1.6.5
    % Originally created 12/2/2019
    % On 12/5/2019, it was updated by Dat Nguyen with some general cosmetic
    % changes and the addition of three lines of code to allow the
    % generation of a square wave. These three lines of code were Mr.
    % Nguyen's entire contribution to this project, which is why I chose
    % not to credit him. Especially since...
    % Revised 12/16 - Undid Dat's changes (rewrote his three lines into one
    % line), added a stop button, added notes/note entry,
    % general cosmetic stuff.
    % 
        
        function createWave(app)
            time = linspace(0,app.duration,app.sampleFreq*app.duration);
            switch app.waveType
                case 1 %Sine
                    ySound = sin(2*pi*app.freq.*time);
                    app.wave = ySound;
                case 2 %Square
                    app.wave = square(2*pi*app.freq.*time,50);
                case 3 %Sawtooth
                    T = 1/app.freq;
                    ySound = mod((time-T),2*T)-T;
                    app.wave = app.freq*ySound;
                otherwise
                    errordlg('Unexpected Error: see createWave fxn','bad');
                    ySound = 0;
                    app.wave = ySound;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sample)
            app.sample = sample; %Assumes it is of class track
            app.waveType = 1;
            app.duration = 0.1;
            app.freq = 261.63;
            app.notes = {'A3','A#3','B3','C4','C#4','D4','D#4','E4','F4'...
                ,'F#4','G4','G#4','A4'};
            % From sengpielaudio.com
            app.noteFreqs = [220,233.082,246.942,261.626,277.183,293.665,...
                311.127,329.628,349.228,369.994,391.995,415.305,440.0];
            app.sampleFreq = 44100;
        end

        % Selection changed function: WavetypeButtonGroup
        function WavetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.WavetypeButtonGroup.SelectedObject;
            switch selectedButton
                case app.SineButton
                    app.waveType = 1;
                case app.SquareButton
                    app.waveType = 2;
                case app.SawtoothButton
                    app.waveType = 3;
                otherwise         
                    errordlg('Unexpected error, see WavetypeButtonGroup...','bad');
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.createWave()
            app.sample.reInitialize(app.wave,app.sampleFreq)
            app.sample.play();
        end

        % Value changed function: DurationsecondsSlider
        function DurationsecondsSliderValueChanged(app, event)
            value = app.DurationsecondsSlider.Value;
            app.duration = value;
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            if value <= 0 
                errordlg('Must be positive and nonzero!', 'bad')
                app.FrequencyEditField.Value = app.middleCFreq;
            else
                app.FrequencyEditField.Value = value;
            end
            app.freq = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sample.stop();
            clear sound;
        end

        % Value changed function: NoteDropDown
        function NoteDropDownValueChanged(app, event)
            value = app.NoteDropDown.Value;
            for i = 1:length(app.notes)
                if strcmpi(value, app.notes{i})
                    app.freq = app.noteFreqs(i);
                    app.FrequencyEditField.Value = app.freq;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DurationsecondsSliderLabel = uilabel(app.UIFigure);
            app.DurationsecondsSliderLabel.Text = {'Duration'; '(seconds)'; ''};
            app.DurationsecondsSlider = uislider(app.UIFigure);
            app.DurationsecondsSlider.Limits = [0 15];
            app.DurationsecondsSlider.MajorTicks = [0 5 10 15];
            app.DurationsecondsSlider.ValueChangedFcn = createCallbackFcn(app, @DurationsecondsSliderValueChanged, true);
            app.WavetypeButtonGroup = uibuttongroup(app.UIFigure);
            app.WavetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WavetypeButtonGroupSelectionChanged, true);
            app.WavetypeButtonGroup.Title = 'Wave type';
            app.SineButton = uiradiobutton(app.WavetypeButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.SquareButton = uiradiobutton(app.WavetypeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SawtoothButton = uiradiobutton(app.WavetypeButtonGroup);
            app.SawtoothButton.Text = 'Sawtooth';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 261.63;
            app.WaveGeneratorLabel = uilabel(app.UIFigure);
            app.WaveGeneratorLabel.Text = 'Wave Generator';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.NoteDropDownLabel = uilabel(app.UIFigure);
            app.NoteDropDownLabel.Text = 'Note';
            app.NoteDropDown = uidropdown(app.UIFigure);
            app.NoteDropDown.Items = {'A3', 'A#3', 'B3', 'C4', 'C#4', 'D4', 'D#4', 'E4', 'F4', 'F#4', 'G4', 'G#4', 'A4'};
            app.NoteDropDown.ItemsData = {'A3', 'A#3', 'B3', 'C4', 'C#4', 'D4', 'D#4', 'E4', 'F4', 'F#4', 'G4', 'G#4', 'A4'};
            app.NoteDropDown.ValueChangedFcn = createCallbackFcn(app, @NoteDropDownValueChanged, true);
            app.NoteDropDown.Value = 'A3';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveGenerator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end